classdef encontrair_raiz_menu < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        Button               matlab.ui.control.Button
        RectasecanteButton   matlab.ui.control.Button
        PuntofijoButton      matlab.ui.control.Button
        NewtonRaphsonButton  matlab.ui.control.Button
        FalsaposicionButton  matlab.ui.control.Button
        BiseccionButton      matlab.ui.control.Button
        PorfavorseleccionaunmetodoparaencontrarlaraizLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            inicio
            delete(app)
        end

        % Button pushed function: BiseccionButton
        function BiseccionButtonPushed(app, event)
            encontrar_raiz("Biseccion")
            delete(app)
        end

        % Button pushed function: FalsaposicionButton
        function FalsaposicionButtonPushed(app, event)
            encontrar_raiz("Falsa posicion")
            delete(app)
        end

        % Button pushed function: RectasecanteButton
        function RectasecanteButtonPushed(app, event)
            encontrar_raiz("Recta secante")
            delete(app)
        end

        % Button pushed function: NewtonRaphsonButton
        function NewtonRaphsonButtonPushed(app, event)
            encontrar_raiz("Newton-Raphson")
            delete(app)
        end

        % Button pushed function: PuntofijoButton
        function PuntofijoButtonPushed(app, event)
            encontrar_raiz("Punto fijo")
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PorfavorseleccionaunmetodoparaencontrarlaraizLabel = uilabel(app.UIFigure);
            app.PorfavorseleccionaunmetodoparaencontrarlaraizLabel.Text = 'Porfavor selecciona un metodo para encontrar la raiz';
            app.BiseccionButton = uibutton(app.UIFigure, 'push');
            app.BiseccionButton.ButtonPushedFcn = createCallbackFcn(app, @BiseccionButtonPushed, true);
            app.BiseccionButton.Text = 'Biseccion';
            app.FalsaposicionButton = uibutton(app.UIFigure, 'push');
            app.FalsaposicionButton.ButtonPushedFcn = createCallbackFcn(app, @FalsaposicionButtonPushed, true);
            app.FalsaposicionButton.Text = 'Falsa posicion';
            app.NewtonRaphsonButton = uibutton(app.UIFigure, 'push');
            app.NewtonRaphsonButton.ButtonPushedFcn = createCallbackFcn(app, @NewtonRaphsonButtonPushed, true);
            app.NewtonRaphsonButton.Text = 'Newton-Raphson';
            app.PuntofijoButton = uibutton(app.UIFigure, 'push');
            app.PuntofijoButton.ButtonPushedFcn = createCallbackFcn(app, @PuntofijoButtonPushed, true);
            app.PuntofijoButton.Text = 'Punto fijo';
            app.RectasecanteButton = uibutton(app.UIFigure, 'push');
            app.RectasecanteButton.ButtonPushedFcn = createCallbackFcn(app, @RectasecanteButtonPushed, true);
            app.RectasecanteButton.Text = 'Recta secante';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = fullfile(pathToMLAPP, 'recursos', 'Icon awesome-home.svg');
            app.Button.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = encontrair_raiz_menu

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end