classdef inicio < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        ToolboxenMatlabparaMtodosNumricosLabel  matlab.ui.control.Label
        AcercadeButton             matlab.ui.control.Button
        AproximacindeproblemasdeCauchyButton  matlab.ui.control.Button
        IntegraciondefinidaButton  matlab.ui.control.Button
        AproximacinderacesButton   matlab.ui.control.Button
        QudeseasrealizarLabel      matlab.ui.control.Label
        BienvenidoLabel            matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: AproximacinderacesButton
        function AproximacinderacesButtonPushed(app, event)
            encontrar_raiz
            delete(app)
        end

        % Button pushed function: IntegraciondefinidaButton
        function IntegraciondefinidaButtonPushed(app, event)
            integrar 
            delete(app)
        end

        % Button pushed function: AproximacindeproblemasdeCauchyButton
        function AproximacindeproblemasdeCauchyButtonPushed(app, event)
            resolver_ode
            delete(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
           opc=questdlg('¿Desea salir del programa?','SALIR','Sí','No','No');
            if strcmp(opc,'No')
                return;
            end
            delete(app)
            
        end

        % Button pushed function: AcercadeButton
        function AcercadeButtonPushed(app, event)
            msgbox("Elaborado por Bryan Ramírez Obando Tecnológico de Costa Rica")
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.BienvenidoLabel = uilabel(app.UIFigure);
            app.BienvenidoLabel.Text = 'Bienvenido';
            app.QudeseasrealizarLabel = uilabel(app.UIFigure);
            app.QudeseasrealizarLabel.Text = '¿Qué deseas realizar?';
            app.AproximacinderacesButton = uibutton(app.UIFigure, 'push');
            app.AproximacinderacesButton.ButtonPushedFcn = createCallbackFcn(app, @AproximacinderacesButtonPushed, true);
            app.AproximacinderacesButton.Text = 'Aproximación de raíces';
            app.IntegraciondefinidaButton = uibutton(app.UIFigure, 'push');
            app.IntegraciondefinidaButton.ButtonPushedFcn = createCallbackFcn(app, @IntegraciondefinidaButtonPushed, true);
            app.IntegraciondefinidaButton.Text = 'Integracion definida';
            app.AproximacindeproblemasdeCauchyButton = uibutton(app.UIFigure, 'push');
            app.AproximacindeproblemasdeCauchyButton.ButtonPushedFcn = createCallbackFcn(app, @AproximacindeproblemasdeCauchyButtonPushed, true);
            app.AproximacindeproblemasdeCauchyButton.Text = 'Aproximación de problemas de Cauchy';
            app.AcercadeButton = uibutton(app.UIFigure, 'push');
            app.AcercadeButton.ButtonPushedFcn = createCallbackFcn(app, @AcercadeButtonPushed, true);
            app.AcercadeButton.Tooltip = {'Botón para rellenar los campos con un ejemplo resuelto'};
            app.AcercadeButton.Text = 'Acerca de';
            app.ToolboxenMatlabparaMtodosNumricosLabel = uilabel(app.UIFigure);
            app.ToolboxenMatlabparaMtodosNumricosLabel.Text = 'Toolbox en Matlab para Métodos Numéricos';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = inicio

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end