classdef integrar < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Button_2                        matlab.ui.control.Button
        Button                          matlab.ui.control.Button
        Panel_3                         matlab.ui.container.Panel
        UITable                         matlab.ui.control.Table
        Panel_2                         matlab.ui.container.Panel
        Image                           matlab.ui.control.Image
        fxLabel                         matlab.ui.control.Label
        GuardarresultadosButton         matlab.ui.control.Button
        EjemploresueltoButton           matlab.ui.control.Button
        FundamentacintericaButton       matlab.ui.control.Button
        IntegracinnumricaLabel          matlab.ui.control.Label
        IntroducelafuncinEditField      matlab.ui.control.EditField
        CalcularButton                  matlab.ui.control.Button
        bEditField                      matlab.ui.control.NumericEditField
        bEditFieldLabel                 matlab.ui.control.Label
        aEditField                      matlab.ui.control.NumericEditField
        aEditFieldLabel                 matlab.ui.control.Label
        SeleccionaelmtodoDropDown       matlab.ui.control.DropDown
        SeleccionaelmtodoDropDownLabel  matlab.ui.control.Label
        nEditField                      matlab.ui.control.NumericEditField
        nEditFieldLabel                 matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            inicio
            delete(app)
        end

        % Button pushed function: CalcularButton
        function CalcularButtonPushed(app, event)
%             try
%                 funcion = str2sym(app.IntroducelafuncinEditField.Value);
%                 vars = symvar(funcion);
%                 a = app.aEditField.Value;
%                 b = app.bEditField.Value;
%                 n = app.nEditField.Value;
%                 tipo_de_metodo = app.SeleccionaelmtodoDropDown.Value;
%                 % ajustes generales para todas las graficas
%                 cla(app.UIAxes,'reset');
%                 grid(app.UIAxes,"on");
%                 h=(b-a)/n;
%                 ejex=linspace(a,b,100);
%                 ejey=subs(funcion,vars(1),ejex);
%                 hold(app.UIAxes,'on');
%                 plot(app.UIAxes,ejex,ejey,'LineWidth',1.5,'Color','r');
%                 x0 = a;
%                 x1 = b;
% 
%                 switch tipo_de_metodo
%                     case "Punto medio compuesto"
%                         resultados = puntomediocompuesto(a,b,funcion,n);
%                         %graficar
%                         for i = 1:round(n)
%                             x1 = x0+h;
%                             ejexaproximado = [x0,x1];
%                             imax=subs(funcion,vars(1),(x0+x1)/2);
%                             ejeyaproximado=[imax,imax];
%                             areasombreada=area(app.UIAxes,ejexaproximado,ejeyaproximado);
%                             areasombreada.FaceColor = [0.01, 0.6, 0.8];
%                             areasombreada.FaceAlpha = 0.3;
%                             x0=x1;
%                         end
%                         hold(app.UIAxes,'off');
%                         legend(app.UIAxes,{'Función','Aproximación por punto medio compuesto'},'Location','best');
%                     case "Punto medio simple"
%                         resultados = puntomediosimple(a,b,funcion);
%                         ejexaprox=[x0,x1];
%                         imax=subs(funcion,vars(1),(x0+x1)/2);
%                         ejeyaprox=[imax,imax];
%                         hold(app.UIAxes,"on")
%                         areasombreada=area(app.UIAxes,ejexaprox,ejeyaprox);
%                         areasombreada.FaceColor = [0.01, 0.6, 0.8];
%                         areasombreada.FaceAlpha = 0.3;
%                         plot(app.UIAxes,ejex,ejey,'LineWidth',1.5,"Color","r");
%                         hold(app.UIAxes,'off');
%                         legend(app.UIAxes,{'Función','Aproximación por punto medio simple'},'Location','best');
% 
% 
%                     case "Trapecio simple"
%                         resultados = trapeciosimple(a,b,funcion);
% 
%                         hold(app.UIAxes,'on');
%                         ejexaprox=[x0,x1];
%                         imax1=subs(funcion,vars(1),x0);
%                         imax2=subs(funcion,vars(1),x1);
%                         ejeyaprox=[imax1,imax2];
%                         areasombreada=area(app.UIAxes,ejexaprox,ejeyaprox);
%                         areasombreada.FaceColor = [0.01, 0.6, 0.8];
%                         areasombreada.FaceAlpha = 0.3;
%                         plot(app.UIAxes,ejex,ejey,'LineWidth',1.5,"Color","r");
%                         hold(app.UIAxes,'off');
%                         legend(app.UIAxes,{'Función','Aproximación por trapecio simple'},'Location','best');
% 
%                     case "Trapecio compuesto"
%                         resultados = trapeciocompuesto(a,b,funcion,n);
%                         hold(app.UIAxes,'on');
%                         plot(app.UIAxes,ejex,ejey,'LineWidth',1.5,'Color','r');
%                         for i=1:n
%                             x1=x0+h;
%                             ejexaprox=[x0,x1];
%                             imax1=subs(funcion,vars(1),x0);
%                             imax2=subs(funcion,vars(1),x1);
%                             ejeyaprox=[imax1,imax2];
%                             areasombreada=area(app.UIAxes,ejexaprox,ejeyaprox);
%                             areasombreada.FaceColor = [0.01, 0.6, 0.8];
%                             areasombreada.FaceAlpha = 0.3;
%                             x0=x1;
%                         end
%                         hold(app.UIAxes,'off');
%                         legend(app.UIAxes,{'Función','Aproximación por trapecio compuesto'},'Location','best');
% 
% 
%                     case "Simpson 3/8 simple"
%                         resultados = simpson3_8simple(a,b,funcion);
%                         f = funcion;
%                         hold(app.UIAxes,'on');
%                         r1 = subs(f,vars(1),a);
%                         r2 = subs(f,vars(1),b);
%                         r3 = 3*subs(f,vars(1),(2*a+b)/3);
%                         r4 = 3*subs(f,vars(1),(a+2*b)/3);
%                         coef = polyfit([a,b,(2*a+b)/3,(a+2*b)/3],[double(r1),double(r2),double(r3),double(r4)],3);
%                         m = (a+b)/2;
%                         checkcoef = [subs(f,vars(1),a)*(((x-m)*(x-b))/((a-m)*(a-b))),...
%                                      subs(f,vars(1),m)*(((x-a)*(x-b))/((m-a)*(m-b))),...
%                                      subs(f,vars(1),b)*(((x-a)*(x-m))/((b-a)*(b-m)))
%                                     ]
%                         fplot(app.UIAxes ,f,[a,b],'LineWidth',1.5,"Color","r")
%                         fgraf = @(x) (coef(1)).*x.^3+ coef(2).*x.^2+coef(3).*x+coef(4);
%                         X = a:0.01:b;
%                         Y = fgraf(X);
%                         areasombreada = area(app.UIAxes,X,Y);
%                         areasombreada.FaceColor = [0.01, 0.6, 0.8];
%                         areasombreada.FaceAlpha = 0.3;
%                         hold(app.UIAxes,'off');
%                         legend(app.UIAxes,{'Función','Aproximación por simpson 3/8'},'Location','best');
% 
%                     case "Simpson 3/8 compuesto"
%                         resultados = simpson3_8compuesto(a,b,funcion,n);
%                         hold(app.UIAxes,'on');
%                         x0 = a;
%                         f = funcion;
%                         r = numel(a:h:b);
%                         for i = 1:r-1
%                             x1 = x0+h;
%                             r1 = subs(f,vars(1),x0);
%                             r2 = 3*subs(f,vars(1),(2*x0-x1)/3);
%                             r3 = 3*subs(f,vars(1),(x0-2*x1)/3);
%                             r4 = subs(f,vars(1),x1);
%                             coef = polyfit([x0,(2*x0-x1)/3,(x0-2*x1)/3,x1],[double(r1),double(r2),double(r3),double(r4)],3);
%                             fgraf = @(x) (coef(1)).*x.^3+coef(2).*x.^2+coef(3).*x+coef(4);
%                             X = x0:0.01:x1;
%                             Y = fgraf(X);
%                             areasombreada = area(app.UIAxes,X,Y);
%                             areasombreada.FaceColor = [0.01, 0.6, 0.8];
%                             areasombreada.FaceAlpha = 0.3;
%                             x0 = x1;
%                         end
% 
%                         fplot(app.UIAxes,f,[a,b],'LineWidth',1.5,"Color","r")
%                         hold(app.UIAxes,'off');
%                         legend(app.UIAxes,{'Función','Aproximación por simpson 3/8'},'Location','best');
% 
%                     case "Simpson 1/3 simple"
%                         resultados = simpson1_3simple(a,b,funcion);
%                         m=(a+b)/2;
%                         f = funcion;
%                         r1 = subs(f,vars(1),a);
%                         r2 = subs(f,vars(1),b);
%                         r3 = 4*subs(f,vars(1),m);
%                         coef = polyfit([a,b,m],[double(r1),double(r2),double(r3)],2);
%                         hold(app.UIAxes,"on");
%                         fplot(app.UIAxes, f,[a,b],'LineWidth',1.5,"Color","r")
%                         fgraf = @(x) (coef(1)).*x.^2+ coef(2).*x+coef(3);
%                         X = a:0.01:b;
%                         Y = fgraf(X);
%                         areasombreada = area(app.UIAxes,X,Y);
%                         areasombreada.FaceColor = [0.01, 0.6, 0.8];
%                         areasombreada.FaceAlpha = 0.3;
% 
%                         hold(app.UIAxes,'off');
%                         legend(app.UIAxes,{'Función','Aproximación por simpson 1/3 simple'},'Location','best');
% 
%                     case "Simpson 1/3 compuesto"
%                         resultados = simpson1_3compuesto(a,b,funcion,n);
%                         hold(app.UIAxes,"on");
%                         r = numel(a:h:b);
%                         f = funcion;
%                         for i = 1:r-1
%                             x1 = x0+h;
%                             r1 = subs(f,vars(1),x0);
%                             r2 = subs(f,vars(1),x1);
%                             r3 = 4*subs(f,vars(1),(x1+x0)/2);
%                             coef = polyfit([x0,x1,(x1-x0)/2],[double(r1),double(r2),double(r3)],2);
%      
%                             fgraf = @(x) (coef(1)).*x.^2+ coef(2).*x+coef(3);
%                             X = x0:0.01:x1;
%                             Y = fgraf(X);
%                             areasombreada = area(app.UIAxes,X,Y);
%                             areasombreada.FaceColor = [0.01, 0.6, 0.8];
%                             areasombreada.FaceAlpha = 0.3;
%                             x0 = x1;
%                         end
%                         fplot(app.UIAxes,f,[a,b],'LineWidth',1.5,"Color","r")      
%                         hold(app.UIAxes,'off');
%                         legend(app.UIAxes,{'Función','Aproximación por simpson 1/3'},'Location','best');
%                 end
%                 app.UITable.ColumnFormat =  {'short','long','long'};
%                 app.UITable.Data = resultados;
% 
%             catch
%                 errordlg("Un error ha ocurrido durante la ejecución, prueba cambiando alguno de los parámetros de entrada.",'Error')
%             end


            try
                funcion = str2sym(app.IntroducelafuncinEditField.Value);
                vars = symvar(funcion);
                if string(vars) ~= "x"
                    errordlg("Las funciones deben de ir respecto a x")
                end
                a = app.aEditField.Value;
                b = app.bEditField.Value;
                n = app.nEditField.Value;
                tipo_de_metodo = app.SeleccionaelmtodoDropDown.Value;
                % ajustes generales para todas las graficas
                cla(app.UIAxes,'reset');
                cla(app.UIAxes)
                grid(app.UIAxes,"on");
                h=(b-a)/n;
                ejex=linspace(a,b,100);
                ejey=subs(funcion,vars(1),ejex);
                hold(app.UIAxes,'on');
                plot(app.UIAxes,ejex,ejey,'LineWidth',1.5,'Color','r');
                x0 = a;
                x1 = b;

                switch tipo_de_metodo
                    case "Punto medio compuesto"
                        resultados = puntomediocompuesto(a,b,funcion,n);
                        %graficar
                        for i = 1:round(n)
                            x1 = x0+h;
                            ejexaproximado = [x0,x1];
                            imax=subs(funcion,vars(1),(x0+x1)/2);
                            ejeyaproximado=[imax,imax];
                            areasombreada=area(app.UIAxes,ejexaproximado,ejeyaproximado);
                            areasombreada.FaceColor = [0.01, 0.6, 0.8];
                            areasombreada.FaceAlpha = 0.3;
                            x0=x1;
                        end
                        hold(app.UIAxes,'off');
                        legend(app.UIAxes,{'Función','Aproximación por punto medio compuesto'},'Location','best');
                    case "Punto medio simple"
                        resultados = puntomediosimple(a,b,funcion);
                        ejexaprox=[x0,x1];
                        imax=subs(funcion,vars(1),(x0+x1)/2);
                        ejeyaprox=[imax,imax];
                        hold(app.UIAxes,"on")
                        areasombreada=area(app.UIAxes,ejexaprox,ejeyaprox);
                        areasombreada.FaceColor = [0.01, 0.6, 0.8];
                        areasombreada.FaceAlpha = 0.3;
                        plot(app.UIAxes,ejex,ejey,'LineWidth',1.5,"Color","r");
                        hold(app.UIAxes,'off');
                        legend(app.UIAxes,{'Función','Aproximación por punto medio simple'},'Location','best');


                    case "Trapecio simple"
                        resultados = trapeciosimple(a,b,funcion);

                        hold(app.UIAxes,'on');
                        ejexaprox=[x0,x1];
                        imax1=subs(funcion,vars(1),x0);
                        imax2=subs(funcion,vars(1),x1);
                        ejeyaprox=[imax1,imax2];
                        areasombreada=area(app.UIAxes,ejexaprox,ejeyaprox);
                        areasombreada.FaceColor = [0.01, 0.6, 0.8];
                        areasombreada.FaceAlpha = 0.3;
                        plot(app.UIAxes,ejex,ejey,'LineWidth',1.5,"Color","r");
                        hold(app.UIAxes,'off');
                        legend(app.UIAxes,{'Función','Aproximación por trapecio simple'},'Location','best');

                    case "Trapecio compuesto"
                        resultados = trapeciocompuesto(a,b,funcion,n);
                        hold(app.UIAxes,'on');
                        plot(app.UIAxes,ejex,ejey,'LineWidth',1.5,'Color','r');
                        for i=1:n
                            x1=x0+h;
                            ejexaprox=[x0,x1];
                            imax1=subs(funcion,vars(1),x0);
                            imax2=subs(funcion,vars(1),x1);
                            ejeyaprox=[imax1,imax2];
                            areasombreada=area(app.UIAxes,ejexaprox,ejeyaprox);
                            areasombreada.FaceColor = [0.01, 0.6, 0.8];
                            areasombreada.FaceAlpha = 0.3;
                            x0=x1;
                        end
                        hold(app.UIAxes,'off');
                        legend(app.UIAxes,{'Función','Aproximación por trapecio compuesto'},'Location','best');


                    case "Simpson 3/8 simple"
                        resultados = simpson3_8simple(a,b,funcion);
                        f = funcion;
                        hold(app.UIAxes,'on')
                        x0 = a;
                        x1 = (2*a+b)/3;
                        x2 = (a+2*b)/3;
                        x3 = b;                                                                                     % posible error en la formula
                        coefsym = subs(f,x0)*((vars(1)-x1)*(vars(1)-x2)*(vars(1)-x3))/((x0-x1)*(x0-x2)*(x0-x3))+...  % checar con la formula
                                  subs(f,x1)*((vars(1)-x0)*(vars(1)-x2)*(vars(1)-x3))/((x1-x0)*(x1-x2)*(x1-x3))+... % checar con la formula
                                  subs(f,x2)*(((vars(1)-x0)*(vars(1)-x1)*(vars(1)-x3))/((x2-x0)*(x2-x1)*(x2-x3)))+...
                                  subs(f,x3)*((vars(1)-x0)*(vars(1)-x1)*(vars(1)-x2))/((x3-x0)*(x3-x1)*(x3-x2));
                        
                        fplot(app.UIAxes ,f,[a,b],'LineWidth',1.5,"Color","r")
                        fgraf = matlabFunction(coefsym);
                        X = a:0.01:b;
                        Y = fgraf(X);
                        areasombreada = area(app.UIAxes,X,Y);
                        areasombreada.FaceColor = [0.01, 0.6, 0.8];
                        areasombreada.FaceAlpha = 0.3;
                        hold(app.UIAxes,'off');
                        legend(app.UIAxes,{'Función','Aproximación por simpson 3/8'},'Location','best');

                    case "Simpson 3/8 compuesto"
                        resultados = simpson3_8compuesto(a,b,funcion,n);
                        hold(app.UIAxes,'on');
                        X0 = a;
                        f = funcion;
                        r = numel(a:h:b);
                        for i = 1:r-1
                            X1 = X0+h;
                            x0 = X0;
                            x1 = (2*X0+X1)/3;
                            x2 = (X0+2*X1)/3;
                            x3 = X1;                                                                                     % posible error en la formula
                            coefsym = subs(f,x0)*((vars(1)-x1)*(vars(1)-x2)*(vars(1)-x3))/((x0-x1)*(x0-x2)*(x0-x3))+...  % checar con la formula
                                      subs(f,x1)*((vars(1)-x0)*(vars(1)-x2)*(vars(1)-x3))/((x1-x0)*(x1-x2)*(x1-x3))+... % checar con la formula
                                      subs(f,x2)*(((vars(1)-x0)*(vars(1)-x1)*(vars(1)-x3))/((x2-x0)*(x2-x1)*(x2-x3)))+...
                                      subs(f,x3)*((vars(1)-x0)*(vars(1)-x1)*(vars(1)-x2))/((x3-x0)*(x3-x1)*(x3-x2))
                            
                            fgraf = matlabFunction(coefsym);
                            X = X0:0.01:X1;
                            Y = fgraf(X);
                            areasombreada = area(app.UIAxes,X,Y);
                            areasombreada.FaceColor = [0.01, 0.6, 0.8];
                            areasombreada.FaceAlpha = 0.3;
                            X0 = X1;
                        end

                        fplot(app.UIAxes,f,[a,b],'LineWidth',1.5,"Color","r")
                        hold(app.UIAxes,'off');
                        legend(app.UIAxes,{'Función','Aproximación por simpson 3/8'},'Location','best');

                    case "Simpson 1/3 simple"
                        resultados = simpson1_3simple(a,b,funcion);
                        f = funcion;
                        hold(app.UIAxes,"on");
                        fplot(app.UIAxes, f,[a,b],'LineWidth',1.5,"Color","r")
                        m = (a+b)/2;
                        checkcoef = subs(f,vars(1),a)*(((vars(1)-m)*(vars(1)-b))/((a-m)*(a-b))) +...
                                     subs(f,vars(1),m)*(((vars(1)-a)*(vars(1)-b))/((m-a)*(m-b))) +...
                                     subs(f,vars(1),b)*(((vars(1)-a)*(vars(1)-m))/((b-a)*(b-m)));
                        fgraf = matlabFunction(checkcoef);            
                        X = a:0.01:b;
                        Y = fgraf(X);
                        areasombreada = area(app.UIAxes,X,Y);
                        areasombreada.FaceColor = [0.01, 0.6, 0.8];
                        areasombreada.FaceAlpha = 0.3;
                    

                        hold(app.UIAxes,'off');
                        legend(app.UIAxes,{'Función','Aproximación por simpson 1/3 simple'},'Location','best');

                    case "Simpson 1/3 compuesto"
                        resultados = simpson1_3compuesto(a,b,funcion,n);
                        hold(app.UIAxes,"on");
                        r = numel(a:h:b);
                        f = funcion;
                        for i = 1:r-1
                            x1 = x0+h
                            m = (x0+x1)/2
                            coef = subs(f,vars(1),x0)*(((vars(1)-m)*(vars(1)-x1))/((x0-m)*(x0-x1))) +...
                                     subs(f,vars(1),m)*(((vars(1)-x0)*(vars(1)-x1))/((m-x0)*(m-x1))) +...
                                     subs(f,vars(1),x1)*(((vars(1)-x0)*(vars(1)-m))/((x1-x0)*(x1-m)))
                            fgraf = matlabFunction(coef)
                            X = x0:0.01:x1
                            Y = fgraf(X)
                            areasombreada = area(app.UIAxes,X,Y);
                            areasombreada.FaceColor = [0.01, 0.6, 0.8];
                            areasombreada.FaceAlpha = 0.3;
                            x0 = x1;
                        end
                        fplot(app.UIAxes,f,[a,b],'LineWidth',1.5,"Color","r")      
                        hold(app.UIAxes,'off');
                        legend(app.UIAxes,{'Función','Aproximación por simpson 1/3'},'Location','best');
                end
                app.UITable.ColumnFormat =  {'short','long','long'};
                app.UITable.Data = resultados;
            catch
                 errordlg("Un error ha ocurrido durante la ejecución, prueba cambiando alguno de los parámetros de entrada.",'Error')
            end


        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
           opc=questdlg('¿Desea salir del programa?','SALIR','Sí','No','No');
            if strcmp(opc,'No')
                return;
            end
            delete(app)
            
        end

        % Button pushed function: EjemploresueltoButton
        function EjemploresueltoButtonPushed(app, event)
            % funcion para el boton de ejemplo resuelto

            funcion = str2sym("x^2");
            vars = symvar(funcion);
            a = 0;
            b = 1;
            n = 7;
            % ajustes generales para todas las graficas
            cla(app.UIAxes,'reset');
            grid(app.UIAxes,"on");
            h=(b-a)/n;
            ejex=linspace(a,b,100);
            ejey=subs(funcion,vars(1),ejex);
            hold(app.UIAxes,'on');
            plot(app.UIAxes,ejex,ejey,'LineWidth',1.5,'Color','r');
            x0 = a;
            x1 = b;
            resultados = puntomediocompuesto(a,b,funcion,n);
            %graficar
            for i = 1:round(n)
                x1 = x0+h;
                ejexaproximado = [x0,x1];
                imax=subs(funcion,vars(1),(x0+x1)/2);
                ejeyaproximado=[imax,imax];
                areasombreada=area(app.UIAxes,ejexaproximado,ejeyaproximado);
                areasombreada.FaceColor = [0.01, 0.6, 0.8];
                areasombreada.FaceAlpha = 0.3;
                x0=x1;
            end
            hold(app.UIAxes,'off');
            legend(app.UIAxes,{'Función','Aproximación por punto medio compuesto'},'Location','best');

            app.UITable.ColumnFormat =  {'short','long','long'};
            app.UITable.Data = resultados;
            app.IntroducelafuncinEditField.Value ="x^2";
            app.aEditField.Value = 0;
            app.bEditField.Value =1;
            app.nEditField.Value = 7;
            app.SeleccionaelmtodoDropDown.Value = "Punto medio compuesto";
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            % funcion para el boton de ayuda
        end

        % Button pushed function: GuardarresultadosButton
        function GuardarresultadosButtonPushed(app, event)
            writematrix(app.UITable.Data,'resultadosIntegrar.csv')
            msgbox("Los datos se han guardado con exito")
        end

        % Value changed function: SeleccionaelmtodoDropDown
        function SeleccionaelmtodoDropDownValueChanged(app, event)
            value = app.SeleccionaelmtodoDropDown.Value;
            switch value
                case "Punto medio simple"
                    app.nEditField.Enable ="off";
                case "Trapecio simple"
                    app.nEditField.Enable ="off";
                case "Simpson 3/8 simple"
                    app.nEditField.Enable ="off";
                case "Simpson 1/3 simple"
                    app.nEditField.Enable ="off";
                case "Punto medio compuesto"
                    app.nEditField.Enable ="on";
                case "Trapecio compuesto"
                    app.nEditField.Enable = "on";
                case "Simpsn 3/8 compuesto"
                    app.nEditField.Enable = 'on';
                case "Simpson 1/3 compuesto"
                    app.nEditField.Enable = "on";

            end
        end

        % Button pushed function: FundamentacintericaButton
        function FundamentacintericaButtonPushed(app, event)
            winopen("metodos_numericos\integrar\teoria\TeoriaIntegracion.pdf")
            winopen("metodos_numericos\integrar\teoria\Ejemploresueltointegracion.pdf")
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Panel = uipanel(app.UIFigure);
            app.UIAxes = uiaxes(app.Panel);
            title(app.UIAxes, 'Función vs Interpolación')
            xlabel(app.UIAxes, 'Eje X')
            ylabel(app.UIAxes, 'Eje Y')
            zlabel(app.UIAxes, 'Z')
            app.Panel_2 = uipanel(app.UIFigure);
            app.nEditFieldLabel = uilabel(app.Panel_2);
            app.nEditFieldLabel.Text = 'n';
            app.nEditField = uieditfield(app.Panel_2, 'numeric');
            app.SeleccionaelmtodoDropDownLabel = uilabel(app.Panel_2);
            app.SeleccionaelmtodoDropDownLabel.Text = 'Selecciona el método ';
            app.SeleccionaelmtodoDropDown = uidropdown(app.Panel_2);
            app.SeleccionaelmtodoDropDown.Items = {'Punto medio compuesto', 'Punto medio simple', 'Trapecio simple', 'Trapecio compuesto', 'Simpson 3/8 simple', 'Simpson 3/8 compuesto', 'Simpson 1/3 simple', 'Simpson 1/3 compuesto'};
            app.SeleccionaelmtodoDropDown.ValueChangedFcn = createCallbackFcn(app, @SeleccionaelmtodoDropDownValueChanged, true);
            app.SeleccionaelmtodoDropDown.Value = 'Punto medio compuesto';
            app.aEditFieldLabel = uilabel(app.Panel_2);
            app.aEditFieldLabel.Text = 'a';
            app.aEditField = uieditfield(app.Panel_2, 'numeric');
            app.aEditField.Tooltip = {'Punto de inicio'};
            app.bEditFieldLabel = uilabel(app.Panel_2);
            app.bEditFieldLabel.Tooltip = {''};
            app.bEditFieldLabel.Text = 'b';
            app.bEditField = uieditfield(app.Panel_2, 'numeric');
            app.bEditField.Tooltip = {'Punto de termino'};
            app.CalcularButton = uibutton(app.Panel_2, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Tooltip = {'Botón para calcular'};
            app.CalcularButton.Text = 'Calcular';
            app.IntroducelafuncinEditField = uieditfield(app.Panel_2, 'text');
            app.IntegracinnumricaLabel = uilabel(app.Panel_2);
            app.IntegracinnumricaLabel.Text = 'Integración numérica';
            app.FundamentacintericaButton = uibutton(app.Panel_2, 'push');
            app.FundamentacintericaButton.ButtonPushedFcn = createCallbackFcn(app, @FundamentacintericaButtonPushed, true);
            app.FundamentacintericaButton.Tooltip = {'Botón para acceder a la teoría del método'};
            app.FundamentacintericaButton.Text = 'Fundamentación teórica';
            app.EjemploresueltoButton = uibutton(app.Panel_2, 'push');
            app.EjemploresueltoButton.ButtonPushedFcn = createCallbackFcn(app, @EjemploresueltoButtonPushed, true);
            app.EjemploresueltoButton.Tooltip = {'Botón que rellena todos los campos con un ejemplo'};
            app.EjemploresueltoButton.Text = 'Ejemplo resuelto';
            app.GuardarresultadosButton = uibutton(app.Panel_2, 'push');
            app.GuardarresultadosButton.ButtonPushedFcn = createCallbackFcn(app, @GuardarresultadosButtonPushed, true);
            app.GuardarresultadosButton.Tooltip = {'Botón para guardar los resultados generados en un archivo de excel'};
            app.GuardarresultadosButton.Text = 'Guardar resultados';
            app.fxLabel = uilabel(app.Panel_2);
            app.fxLabel.Interpreter = 'latex';
            app.fxLabel.Text = 'f(x) = ';
            app.Image = uiimage(app.Panel_2);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'recursos', 'integral (1).svg');
            app.Panel_3 = uipanel(app.UIFigure);
            app.UITable = uitable(app.Panel_3);
            app.UITable.ColumnName = {'Subintervalo'; 'Aproximación'; 'Suma total'};
            app.UITable.RowName = {};
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = fullfile(pathToMLAPP, 'recursos', 'Icon awesome-home.svg');
            app.Button.Tooltip = {'Regresar al inicio'};
            app.Button.Text = '';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Icon = fullfile(pathToMLAPP, 'recursos', 'Icon feather-help-circle@2x.png');
            app.Button_2.Tooltip = {'Botónde ayuda'};
            app.Button_2.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = integrar

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end