classdef integrar_menu < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        Simpson38simpleButton      matlab.ui.control.Button
        TrapeciocompuestoButton    matlab.ui.control.Button
        Simpson13compuestoButton   matlab.ui.control.Button
        PuntomediocompuestoButton  matlab.ui.control.Button
        Simpson13simpleButton      matlab.ui.control.Button
        Simpson38compuestoButton   matlab.ui.control.Button
        TrapeciosimpleButton       matlab.ui.control.Button
        PuntomediosimpleButton     matlab.ui.control.Button
        Button                     matlab.ui.control.Button
        PorfavorseleccionaunmetodoparaintegrarLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            inicio
            delete(app)
        end

        % Button pushed function: PuntomediocompuestoButton
        function PuntomediocompuestoButtonPushed(app, event)
            integrar("Punto medio compuesto")
            delete(app)
        end

        % Button pushed function: PuntomediosimpleButton
        function PuntomediosimpleButtonPushed(app, event)
            integrar("Punto medio simple")
            delete(app)
        end

        % Button pushed function: Simpson13simpleButton
        function Simpson13simpleButtonPushed(app, event)
            integrar("Simpson 1/3 simple")
            delete(app)
        end

        % Button pushed function: Simpson13compuestoButton
        function Simpson13compuestoButtonPushed(app, event)
            integrar("Simpson 1/3 compuesto")
            delete(app)
        end

        % Button pushed function: TrapeciosimpleButton
        function TrapeciosimpleButtonPushed(app, event)
            integrar("Trapecio simple")
            delete(app)
        end

        % Button pushed function: TrapeciocompuestoButton
        function TrapeciocompuestoButtonPushed(app, event)
            integrar("Trapecio compuesto")
            delete(app)
        end

        % Button pushed function: Simpson38compuestoButton
        function Simpson38compuestoButtonPushed(app, event)
            integrar("Simpson 3/8 compuesto")
            delete(app)
        end

        % Button pushed function: Simpson38simpleButton
        function Simpson38simpleButtonPushed(app, event)
            integrar("Simpson 3/8 simple")
            delete(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            opc=questdlg('¿Desea salir del programa?','SALIR','Sí','No','No');
            if strcmp(opc,'No')
                return;
            end
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.PorfavorseleccionaunmetodoparaintegrarLabel = uilabel(app.UIFigure);
            app.PorfavorseleccionaunmetodoparaintegrarLabel.Text = 'Porfavor selecciona un metodo para integrar';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = fullfile(pathToMLAPP, 'recursos', 'Icon awesome-home.svg');
            app.Button.Text = '';
            app.PuntomediosimpleButton = uibutton(app.UIFigure, 'push');
            app.PuntomediosimpleButton.ButtonPushedFcn = createCallbackFcn(app, @PuntomediosimpleButtonPushed, true);
            app.PuntomediosimpleButton.Text = 'Punto medio simple';
            app.TrapeciosimpleButton = uibutton(app.UIFigure, 'push');
            app.TrapeciosimpleButton.ButtonPushedFcn = createCallbackFcn(app, @TrapeciosimpleButtonPushed, true);
            app.TrapeciosimpleButton.Text = 'Trapecio simple';
            app.Simpson38compuestoButton = uibutton(app.UIFigure, 'push');
            app.Simpson38compuestoButton.ButtonPushedFcn = createCallbackFcn(app, @Simpson38compuestoButtonPushed, true);
            app.Simpson38compuestoButton.Text = 'Simpson 3/8 compuesto';
            app.Simpson13simpleButton = uibutton(app.UIFigure, 'push');
            app.Simpson13simpleButton.ButtonPushedFcn = createCallbackFcn(app, @Simpson13simpleButtonPushed, true);
            app.Simpson13simpleButton.Text = 'Simpson 1/3 simple';
            app.PuntomediocompuestoButton = uibutton(app.UIFigure, 'push');
            app.PuntomediocompuestoButton.ButtonPushedFcn = createCallbackFcn(app, @PuntomediocompuestoButtonPushed, true);
            app.PuntomediocompuestoButton.Text = 'Punto medio compuesto';
            app.Simpson13compuestoButton = uibutton(app.UIFigure, 'push');
            app.Simpson13compuestoButton.ButtonPushedFcn = createCallbackFcn(app, @Simpson13compuestoButtonPushed, true);
            app.Simpson13compuestoButton.Text = 'Simpson 1/3 compuesto';
            app.TrapeciocompuestoButton = uibutton(app.UIFigure, 'push');
            app.TrapeciocompuestoButton.ButtonPushedFcn = createCallbackFcn(app, @TrapeciocompuestoButtonPushed, true);
            app.TrapeciocompuestoButton.Text = 'Trapecio compuesto';
            app.Simpson38simpleButton = uibutton(app.UIFigure, 'push');
            app.Simpson38simpleButton.ButtonPushedFcn = createCallbackFcn(app, @Simpson38simpleButtonPushed, true);
            app.Simpson38simpleButton.Text = 'Simpson 3/8 simple';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = integrar_menu

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end