classdef resolver_ode < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Button_2                        matlab.ui.control.Button
        Button                          matlab.ui.control.Button
        Panel_3                         matlab.ui.container.Panel
        UITable                         matlab.ui.control.Table
        Panel_2                         matlab.ui.container.Panel
        yLabel                          matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        problemasdeCauchyLabel          matlab.ui.control.Label
        GuardarresultadosButton         matlab.ui.control.Button
        EjemploresueltoButton           matlab.ui.control.Button
        FundamentacintericaButton       matlab.ui.control.Button
        AproximacinparaLabel            matlab.ui.control.Label
        IntroducelafuncinEditField      matlab.ui.control.EditField
        CalcularButton                  matlab.ui.control.Button
        bEditField                      matlab.ui.control.NumericEditField
        bEditFieldLabel                 matlab.ui.control.Label
        aEditField                      matlab.ui.control.NumericEditField
        aEditFieldLabel                 matlab.ui.control.Label
        SeleccionaelmtodoDropDown       matlab.ui.control.DropDown
        SeleccionaelmtodoDropDownLabel  matlab.ui.control.Label
        nEditField                      matlab.ui.control.NumericEditField
        nEditFieldLabel                 matlab.ui.control.Label
        y0EditField                     matlab.ui.control.NumericEditField
        y0Label                         matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            inicio
            delete(app)
        end

        % Button pushed function: CalcularButton
        function CalcularButtonPushed(app, event)
             try
                textofuncion = app.IntroducelafuncinEditField.Value; % se obtiene el valor del campo funcion
                % todo este bloque es por si el usuario ingreso una funcion
                % anonima se valida si tiene el caracter de @ y si no lo
                % inlcuyo se generara una funcion simbolica que despues se
                % pasara a anonima para asi evitar errores
                if contains(textofuncion,"@")
                    funcion = str2func(textofuncion);
                elseif ~contains(textofuncion,"@")
                    funcion = matlabFunction(str2sym(textofuncion));
                end
%                 vars = symvar(sym(funcion));
% 
% %                 if vars(1) ~= "x" || vars(2) ~= "y"
% %                     errordlg("La funcion debe de estar en terminos de x & y")
% %                 end
                y0 = app.y0EditField.Value; % obtener condicion inicial
                b = app.bEditField.Value; % obtener punto final
                a = app.aEditField.Value; % obtener punto inicial
                t = app.nEditField.Value; % obtener ya sea el tamaño del paso o el numero de elementos
                if t < 1 % si es menor a 1 es un paso
                    n = round((b-a)/t); % se convierte a numero de elementos
                else
                    n = t; % se deja tal como estaba
                end
                metodo = app.SeleccionaelmtodoDropDown.Value;
                % casos para llamar al metodo que haya elegido el usuario
                switch metodo
                    case "Euler"
                        sol = eulerODE(funcion,a,b,y0,n);
                    case "Taylor orden 2"
                        sol = taylorOrden2ODE(funcion,a,b,y0,n);
                    case "Taylor orden 3"
                        sol = taylorOrden3ODE(funcion,a,b,y0,n);
                    case "Runge-Kutta 2"
                        sol = rungeKutta2(funcion,a,b,y0,n);
                    case "Runge-Kutta 4"
                        sol = rungeKutta4(funcion,a,b,y0,n);
                end
                plot(app.UIAxes,sol(:,1),sol(:,2)) % graficar
                app.UITable.ColumnFormat = {'short','long','long'};
                largo = size(sol);
                l = 1:largo;
                app.UITable.Data = [l' sol];

            catch
                errordlg("Un error ha ocurrido durante la ejecución, prueba cambiando alguno de los parámetros de entrada.",'Error')


            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
              opc=questdlg('¿Desea salir del programa?','SALIR','Sí','No','No');
            if strcmp(opc,'No')
                return;
            end
            delete(app)
            
            
        end

        % Button pushed function: EjemploresueltoButton
        function EjemploresueltoButtonPushed(app, event)
            % funcion para el boton ejemplo resuelto
            funcion = @(x,y) x.^2-y.^2-cos(x).*y;
            app.IntroducelafuncinEditField.Value = "@(x,y) x.^2-y.^2-cos(x).*y";
            app.y0EditField.Value = 1;
            app.aEditField.Value = 0;
            app.bEditField.Value = 3;
            app.nEditField.Value = 200;
            app.SeleccionaelmtodoDropDown.Value = "Runge-Kutta 4";
            sol = rungeKutta4(funcion,0,3,1,200);
            plot(app.UIAxes,sol(:,1),sol(:,2)) % graficar
            app.UITable.ColumnFormat = {'short','long','long'};
            largo = size(sol);
            l = 1:largo;
            app.UITable.Data = [l' sol];
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            % funcion para el boton de ayuda
        end

        % Button pushed function: GuardarresultadosButton
        function GuardarresultadosButtonPushed(app, event)
         
          
            writematrix(app.UITable.Data,'resultadosODE.csv')
            msgbox("Los datos se han guardado con exito")
        end

        % Button pushed function: FundamentacintericaButton
        function FundamentacintericaButtonPushed(app, event)
            winopen("metodos_numericos\resolver_ODE\teoria\EjemploresueltoEDO.pdf")
            winopen("metodos_numericos\resolver_ODE\teoria\TeoriaEDO.pdf")
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Panel = uipanel(app.UIFigure);
            app.UIAxes = uiaxes(app.Panel);
            title(app.UIAxes, 'Función')
            xlabel(app.UIAxes, 'Eje X')
            ylabel(app.UIAxes, 'Eje Y')
            zlabel(app.UIAxes, 'Z')
            app.Panel_2 = uipanel(app.UIFigure);
            app.y0Label = uilabel(app.Panel_2);
            app.y0Label.Tooltip = {'Condicion inicial'};
            app.y0Label.Text = 'y0';
            app.y0EditField = uieditfield(app.Panel_2, 'numeric');
            app.y0EditField.Tooltip = {'Condición inicial'};
            app.nEditFieldLabel = uilabel(app.Panel_2);
            app.nEditFieldLabel.Text = ' n';
            app.nEditField = uieditfield(app.Panel_2, 'numeric');
            app.nEditField.Tooltip = {'Cantidad de subintervalos'};
            app.SeleccionaelmtodoDropDownLabel = uilabel(app.Panel_2);
            app.SeleccionaelmtodoDropDownLabel.Text = 'Selecciona el método ';
            app.SeleccionaelmtodoDropDown = uidropdown(app.Panel_2);
            app.SeleccionaelmtodoDropDown.Items = {'Euler', 'Taylor orden 2', 'Taylor orden 3', 'Runge-Kutta 2', 'Runge-Kutta 4'};
            app.SeleccionaelmtodoDropDown.Value = 'Euler';
            app.aEditFieldLabel = uilabel(app.Panel_2);
            app.aEditFieldLabel.Text = 'a';
            app.aEditField = uieditfield(app.Panel_2, 'numeric');
            app.aEditField.Tooltip = {'Punto de inicio '};
            app.bEditFieldLabel = uilabel(app.Panel_2);
            app.bEditFieldLabel.Text = 'b';
            app.bEditField = uieditfield(app.Panel_2, 'numeric');
            app.bEditField.Tooltip = {'Punto de termino '};
            app.CalcularButton = uibutton(app.Panel_2, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Tooltip = {'Botón para calcular '};
            app.CalcularButton.Text = 'Calcular';
            app.IntroducelafuncinEditField = uieditfield(app.Panel_2, 'text');
            app.AproximacinparaLabel = uilabel(app.Panel_2);
            app.AproximacinparaLabel.Text = 'Aproximación para';
            app.FundamentacintericaButton = uibutton(app.Panel_2, 'push');
            app.FundamentacintericaButton.ButtonPushedFcn = createCallbackFcn(app, @FundamentacintericaButtonPushed, true);
            app.FundamentacintericaButton.Tooltip = {'Botón para acceder a la teoría del método'};
            app.FundamentacintericaButton.Text = 'Fundamentación teórica';
            app.EjemploresueltoButton = uibutton(app.Panel_2, 'push');
            app.EjemploresueltoButton.ButtonPushedFcn = createCallbackFcn(app, @EjemploresueltoButtonPushed, true);
            app.EjemploresueltoButton.Tooltip = {'Botón para rellenar los campos con un ejemplo resuelto'};
            app.EjemploresueltoButton.Text = 'Ejemplo resuelto';
            app.GuardarresultadosButton = uibutton(app.Panel_2, 'push');
            app.GuardarresultadosButton.ButtonPushedFcn = createCallbackFcn(app, @GuardarresultadosButtonPushed, true);
            app.GuardarresultadosButton.Tooltip = {'Botón para guardar los resultados generados en un archivo de excel'};
            app.GuardarresultadosButton.Text = 'Guardar resultados';
            app.problemasdeCauchyLabel = uilabel(app.Panel_2);
            app.problemasdeCauchyLabel.Text = 'problemas de Cauchy';
            app.Image = uiimage(app.Panel_2);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'recursos', 'edo.svg');
            app.yLabel = uilabel(app.Panel_2);
            app.yLabel.Interpreter = 'latex';
            app.yLabel.Text = 'y'' = ';
            app.Panel_3 = uipanel(app.UIFigure);
            app.UITable = uitable(app.Panel_3);
            app.UITable.ColumnName = {'Iteración '; 'x'; 'y'};
            app.UITable.RowName = {};
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = fullfile(pathToMLAPP, 'recursos', 'Icon awesome-home.svg');
            app.Button.Tooltip = {'Regresar al inicio'};
            app.Button.Text = '';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Icon = fullfile(pathToMLAPP, 'recursos', 'Icon feather-help-circle@2x.png');
            app.Button_2.Tooltip = {'Botón de ayuda'};
            app.Button_2.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = resolver_ode

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end