classdef resolver_ode_menu < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        RungeKutta4Button  matlab.ui.control.Button
        Taylor3Button      matlab.ui.control.Button
        Taylor2Button      matlab.ui.control.Button
        RungeKutta2Button  matlab.ui.control.Button
        EulerButton        matlab.ui.control.Button
        Button             matlab.ui.control.Button
        PorfavorseleccionaunmetodopararesolverunaODELabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            inicio
            delete(app)
        end

        % Button pushed function: EulerButton
        function EulerButtonPushed(app, event)
            resolver_ode("Euler")
            delete(app)
        end

        % Button pushed function: RungeKutta2Button
        function RungeKutta2ButtonPushed(app, event)
            resolver_ode("Runge-Kutta 2")
            delete(app)
        end

        % Button pushed function: RungeKutta4Button
        function RungeKutta4ButtonPushed(app, event)
            resolver_ode("Runge-Kutta 4")
            delete(app)
        end

        % Button pushed function: Taylor2Button
        function Taylor2ButtonPushed(app, event)
            resolver_ode("Taylor orden 2")
            delete(app)
        end

        % Button pushed function: Taylor3Button
        function Taylor3ButtonPushed(app, event)
            resolver_ode("Taylor orden 3")
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PorfavorseleccionaunmetodopararesolverunaODELabel = uilabel(app.UIFigure);
            app.PorfavorseleccionaunmetodopararesolverunaODELabel.Text = 'Porfavor selecciona un metodo para resolver una ODE';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = fullfile(pathToMLAPP, 'recursos', 'Icon awesome-home.svg');
            app.Button.Text = '';
            app.EulerButton = uibutton(app.UIFigure, 'push');
            app.EulerButton.ButtonPushedFcn = createCallbackFcn(app, @EulerButtonPushed, true);
            app.EulerButton.Text = 'Euler';
            app.RungeKutta2Button = uibutton(app.UIFigure, 'push');
            app.RungeKutta2Button.ButtonPushedFcn = createCallbackFcn(app, @RungeKutta2ButtonPushed, true);
            app.RungeKutta2Button.Text = 'Runge-Kutta 2';
            app.Taylor2Button = uibutton(app.UIFigure, 'push');
            app.Taylor2Button.ButtonPushedFcn = createCallbackFcn(app, @Taylor2ButtonPushed, true);
            app.Taylor2Button.Text = 'Taylor 2';
            app.Taylor3Button = uibutton(app.UIFigure, 'push');
            app.Taylor3Button.ButtonPushedFcn = createCallbackFcn(app, @Taylor3ButtonPushed, true);
            app.Taylor3Button.Text = 'Taylor 3';
            app.RungeKutta4Button = uibutton(app.UIFigure, 'push');
            app.RungeKutta4Button.ButtonPushedFcn = createCallbackFcn(app, @RungeKutta4ButtonPushed, true);
            app.RungeKutta4Button.Text = 'Runge-Kutta 4';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = resolver_ode_menu

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end